classdef imNawa_forOpticalFlow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image_4                    matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        DICOMPreviewTab            matlab.ui.container.Tab
        Preview                    matlab.ui.container.Panel
        StartAnalysisButton        matlab.ui.control.Button
        AnalysisMode               matlab.ui.control.DropDown
        AnalysisModeDropDownLabel  matlab.ui.control.Label
        OPENDICOMButton            matlab.ui.control.Button
        previewPanel               matlab.ui.container.Panel
        AnalysisTab                matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        ROIsetManuallyTab          matlab.ui.container.Tab
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ThisAreawillbeinstantiablebynawaLabel  matlab.ui.control.Label
        ThreasholdROITab           matlab.ui.container.Tab
        OpticalFlowCaluclateTab    matlab.ui.container.Tab
        SaveTab                    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        dcmHandler
        imageViewer

        
    end
    
    methods (Access = private)
        
        function spawnWindowControlListener(app)
            addlistener(app.imageViewer.histogrammer, 'changeArea', @windowChanging)
            function windowChanging(src, event)
                app.imageViewer.updateImage();
            end
        end
        
%         %改修予定‼‼‼‼
%         function createAnalysis(app)
%             
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            openingProg = uiprogressdlg(app.UIFigure,'Title','計算機能の準備中...',...
            'Indeterminate','on');
            drawnow;
            parpool;
            

            app.imageViewer = scripts.imageViewer.guiImageViewer("figure",app.UIFigure,"parent", app.Preview);
            
            close(openingProg);
        end

        % Button pushed function: OPENDICOMButton
        function OPENDICOMButtonPushed(app, event)
            app.dcmHandler = scripts.dicomHandler.DICOMHandler();
            app.dcmHandler.readDICOMDIR();
            app.dcmHandler.readAllImage();
            
            app.imageViewer.setImages(app.dcmHandler.image3D);

            app.spawnWindowControlListener();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,sprintf('アプリを終了します\n再起動には時間がかかりますがよろしいですか。'),'終了', ...
            'CloseFcn',@(src, event) appCloseConfirm(src, event, app));

            function appCloseConfirm(~, event, app)
            if event.SelectedOption == "OK"
                uiprogressdlg(app.UIFigure,'Title','計算機能のシャットダウン中...',...
                'Indeterminate','on');
                delete(gcp);
                delete(app.UIFigure);
            end
        end
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.AnalysisTab;
%             app.createAnalysis();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DICOMPreviewTab = uitab(app.TabGroup);
            app.DICOMPreviewTab.Title = 'DICOM Preview';
            app.previewPanel = uipanel(app.DICOMPreviewTab);
            app.OPENDICOMButton = uibutton(app.DICOMPreviewTab, 'push');
            app.OPENDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @OPENDICOMButtonPushed, true);
            app.OPENDICOMButton.Text = 'OPEN DICOM';
            app.AnalysisModeDropDownLabel = uilabel(app.DICOMPreviewTab);
            app.AnalysisModeDropDownLabel.Text = 'Analysis Mode';
            app.AnalysisMode = uidropdown(app.DICOMPreviewTab);
            app.AnalysisMode.Items = {'Optical flow', 'not yet', 'comming soon ...'};
            app.AnalysisMode.Value = 'Optical flow';
            app.StartAnalysisButton = uibutton(app.DICOMPreviewTab, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.Preview = uipanel(app.DICOMPreviewTab);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TabGroup2 = uitabgroup(app.AnalysisTab);
            app.ROIsetManuallyTab = uitab(app.TabGroup2);
            app.ROIsetManuallyTab.Title = 'ROI set Manually';
            app.ThisAreawillbeinstantiablebynawaLabel = uilabel(app.ROIsetManuallyTab);
            app.ThisAreawillbeinstantiablebynawaLabel.Text = 'This Area will be instantiable. by nawa';
            app.Image = uiimage(app.ROIsetManuallyTab);
            app.Image_2 = uiimage(app.ROIsetManuallyTab);
            app.Image_3 = uiimage(app.ROIsetManuallyTab);
            app.ThreasholdROITab = uitab(app.TabGroup2);
            app.ThreasholdROITab.Title = 'Threashold ROI';
            app.OpticalFlowCaluclateTab = uitab(app.TabGroup2);
            app.OpticalFlowCaluclateTab.Title = 'Optical Flow Caluclate';
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.Image_4 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imNawa_forOpticalFlow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end