classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        sliceSlider               matlab.ui.control.Slider
        sliceSliderLabel          matlab.ui.control.Label
        windowLevelSlider         matlab.ui.control.Slider
        windowLevelSlider_2Label  matlab.ui.control.Label
        windowWidthSlider         matlab.ui.control.Slider
        windowWidthSlider_2Label  matlab.ui.control.Label
        ReadFilesButton           matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        SelectFilesButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        dcmHandler
        displayImageCells
        currentDisplaySource
        currentDisplayImg
        windowWidthHalf = .5
        windowLevel = .5
    end

    methods (Access = private)

        function mountImg(app, grayImg)
            app.Image.ImageSource = cat(3, grayImg, grayImg, grayImg);
            drawnow;
        end
        
        function appCloseConfirm(app, src, event)
            if event.SelectedOption == "OK"
                uiprogressdlg(app.UIFigure,'Title','計算機能のシャットダウン中...',...
                'Indeterminate','on');
                drawnow;%これいる？
                delete(gcp);
                delete(app.UIFigure);
            end
        end


        function [minW, maxW] = applyWindowForDisplay(app, wl, wwh)
            
            minW = max([wl - wwh, 0]);
            maxW = min([wl + wwh, 1]);

%           imadjustでガンマ補正できます
            app.currentDisplayImg = imadjust(app.currentDisplaySource, [minW, maxW]);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % 必須アドオン
            % Parallel Computing Toolbox
            % Image Processing Toolbox
            % 使用スクリプト
            % DICOMHander.m
            % convertForDisplay.m

            openingProg = uiprogressdlg(app.UIFigure,'Title','計算機能の準備中...',...
            'Indeterminate','on');
            drawnow;
            parpool;
            close(openingProg);
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            try

                app.dcmHandler = scripts.dicomHandler.DICOMHandler();
                app.dcmHandler.readDICOMDIR();
                
                %%%%% thread-base %%%%%%
                [~, previewScaledImg] = app.dcmHandler.getPreview();

                app.currentDisplaySource = scripts.guiImageHandler.convertForDisplay(previewScaledImg);
                app.mountImg(app.currentDisplaySource);
                app.currentDisplayImg = app.currentDisplaySource;

                app.Image.Enable = 'on';
                app.windowLevelSlider.Enable = "on";
                app.windowWidthSlider.Enable = "on";

                %解析設定（ROI設定など）をこの時点でできるようにする予定
                %↑こっちをbackgroundでやる -> 予定。

                %%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.dcmHandler.readAllDcmInfo();
                imgCells = app.dcmHandler.readAllImage();
                app.displayImageCells = scripts.guiImageHandler.convertAllForDisplay(imgCells);
                
                %スライダーの向きは変える予定
%                 app.readComplete.Color = [0,1,0];
                app.sliceSlider.Limits = [1, app.dcmHandler.dcmFileNum];
                app.sliceSlider.Enable = "on";
                

            catch
                return
            end


        end

        % Value changing function: windowWidthSlider
        function windowWidthChanging(app, event)
            [minW, maxW] = app.applyWindowForDisplay(app.windowLevel, event.Value);
            app.mountImg(app.currentDisplayImg);

            app.windowWidthHalf = max([(maxW - minW)/2, 0.001]);
        end

        % Value changing function: windowLevelSlider
        function windowLevelChanging(app, event)
            if (event.Value + app.windowWidthHalf) > 1
                center = 1-app.windowWidthHalf;
            elseif (event.Value - app.windowWidthHalf) < 0
                center = 0 + app.windowWidthHalf;
            else
                center = event.Value;
            end

            app.applyWindowForDisplay(center, app.windowWidthHalf);
            app.mountImg(app.currentDisplayImg);

            app.windowLevel = center;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            preview = figure;
            figure(preview);

            imshow(app.currentDisplayImg, 'InitialMagnification','fit');
            truesize(preview, [512, 512]);%実際の画像サイズを入れる予定
        end

        % Value changing function: sliceSlider
        function sliceSliderValueChanging(app, event)
            sliceNumber = round(event.Value);
            app.currentDisplaySource = app.displayImageCells{sliceNumber};

            app.applyWindowForDisplay(app.windowLevel, app.windowWidthHalf);
            app.mountImg(app.currentDisplayImg);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,sprintf('アプリを終了します\n再起動には時間がかかりますがよろしいですか。'),'終了', ...
            'CloseFcn',@app.appCloseConfirm);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'preview.png');
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.Text = 'Read Files';
            app.windowWidthSlider_2Label = uilabel(app.UIFigure);
            app.windowWidthSlider_2Label.Text = {'window'; 'Width'};
            app.windowWidthSlider = uislider(app.UIFigure);
            app.windowWidthSlider.Limits = [0.001 0.5];
            app.windowWidthSlider.MajorTickLabels = {''};
            app.windowWidthSlider.ValueChangingFcn = createCallbackFcn(app, @windowWidthChanging, true);
            app.windowWidthSlider.Enable = 'off';
            app.windowWidthSlider.Value = 0.5;
            app.windowLevelSlider_2Label = uilabel(app.UIFigure);
            app.windowLevelSlider_2Label.Text = {'window'; 'Level'};
            app.windowLevelSlider = uislider(app.UIFigure);
            app.windowLevelSlider.Limits = [0.001 0.999];
            app.windowLevelSlider.MajorTickLabels = {''};
            app.windowLevelSlider.ValueChangingFcn = createCallbackFcn(app, @windowLevelChanging, true);
            app.windowLevelSlider.Enable = 'off';
            app.windowLevelSlider.Value = 0.5;
            app.sliceSliderLabel = uilabel(app.UIFigure);
            app.sliceSliderLabel.Text = 'slice';
            app.sliceSlider = uislider(app.UIFigure);
            app.sliceSlider.ValueChangingFcn = createCallbackFcn(app, @sliceSliderValueChanging, true);
            app.sliceSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end