classdef testapp_newBranch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        sourceTab                   matlab.ui.container.Tab
        AnalysisMode                matlab.ui.container.ButtonGroup
        Button_2                    matlab.ui.control.RadioButton
        Button                      matlab.ui.control.RadioButton
        OpticalFlowButton           matlab.ui.control.RadioButton
        StartAnarysisButton         matlab.ui.control.Button
        WLSliderSource              matlab.ui.control.Slider
        windowLevelSlider_2Label    matlab.ui.control.Label
        WWSliderSource              matlab.ui.control.Slider
        windowWidthSlider_2Label    matlab.ui.control.Label
        sliceSliderSource           matlab.ui.control.Slider
        sliceSliderLabel            matlab.ui.control.Label
        SourcePreview               matlab.ui.control.Image
        OPENDICOMButton             matlab.ui.control.Button
        AnalysisTab                 matlab.ui.container.Tab
        ConfirmButton               matlab.ui.control.Button
        ProgressButtonGroup         matlab.ui.container.ButtonGroup
        MainCalucutationButton      matlab.ui.control.RadioButton
        ThresholdButton             matlab.ui.control.RadioButton
        ROIButton                   matlab.ui.control.RadioButton
        WLSliderAnalysis            matlab.ui.control.Slider
        windowLevelSlider_2Label_2  matlab.ui.control.Label
        WWSliderAnalysis            matlab.ui.control.Slider
        windowWidthSlider_2Label_2  matlab.ui.control.Label
        sliceSliderAnalysis         matlab.ui.control.Slider
        sliceSlider_2Label          matlab.ui.control.Label
        analysisPreview             matlab.ui.control.Image
        ExportTab                   matlab.ui.container.Tab
        exportPreview               matlab.ui.control.Image
    end

    
    properties (Access = private)
        dcmHandler
        displayImageCells
        currentDisplaySource
        currentDisplayImg
        windowWidthHalf = .5
        windowLevel = .5
    end

    methods (Access = private)

        function mountImg(app, grayImg)
            app.SourcePreview.ImageSource = cat(3, grayImg, grayImg, grayImg);
            drawnow;
        end
        
        function appCloseConfirm(app, src, event)
            if event.SelectedOption == "OK"
                uiprogressdlg(app.UIFigure,'Title','計算機能のシャットダウン中...',...
                'Indeterminate','on');
                drawnow;%これいる？
                delete(gcp);
                delete(app.UIFigure);
            end
        end


        function [minW, maxW] = applyWindowForDisplay(app, wl, wwh)
            
            minW = max([wl - wwh, 0]);
            maxW = min([wl + wwh, 1]);

%           imadjustでガンマ補正できます
            app.currentDisplayImg = imadjust(app.currentDisplaySource, [minW, maxW]);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % 必須アドオン
            % Parallel Computing Toolbox
            % Image Processing Toolbox
            % 使用スクリプト
            % DICOMHander.m
            % convertForDisplay.m

            openingProg = uiprogressdlg(app.UIFigure,'Title','計算機能の準備中...',...
            'Indeterminate','on');
            drawnow;
            parpool;
            close(openingProg);
        end

        % Button pushed function: OPENDICOMButton
        function OPENDICOMButtonPushed(app, event)
            try

                app.dcmHandler = scripts.dicomHandler.DICOMHandler();
                app.dcmHandler.readDICOMDIR();
                
                %%%%% thread-base %%%%%%
                [~, previewScaledImg] = app.dcmHandler.getPreview();

                app.currentDisplaySource = scripts.guiImageHandler.convertForDisplay(previewScaledImg);
                app.mountImg(app.currentDisplaySource);
                app.currentDisplayImg = app.currentDisplaySource;

                app.SourcePreview.Enable = 'on';
                app.WLSliderSource.Enable = "on";
                app.WWSliderSource.Enable = "on";

                %解析設定（ROI設定など）をこの時点でできるようにする予定
                %↑こっちをbackgroundでやる -> 予定。

                %%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.dcmHandler.readAllDcmInfo();
                imgCells = app.dcmHandler.readAllImage();
                app.displayImageCells = scripts.guiImageHandler.convertAllForDisplay(imgCells);
                
                %スライダーの向きは変える予定
%                 app.readComplete.Color = [0,1,0];
                app.sliceSliderSource.Limits = [1, app.dcmHandler.dcmFileNum];
                app.sliceSliderSource.Enable = "on";
                

            catch
                return
            end


        end

        % Value changing function: WWSliderSource
        function windowWidthChanging(app, event)
            [minW, maxW] = app.applyWindowForDisplay(app.windowLevel, event.Value);
            app.mountImg(app.currentDisplayImg);

            app.windowWidthHalf = max([(maxW - minW)/2, 0.001]);
        end

        % Value changing function: WLSliderSource
        function windowLevelChanging(app, event)
            if (event.Value + app.windowWidthHalf) > 1
                center = 1-app.windowWidthHalf;
            elseif (event.Value - app.windowWidthHalf) < 0
                center = 0 + app.windowWidthHalf;
            else
                center = event.Value;
            end

            app.applyWindowForDisplay(center, app.windowWidthHalf);
            app.mountImg(app.currentDisplayImg);

            app.windowLevel = center;
        end

        % Image clicked function: SourcePreview
        function SourcePreviewClicked(app, event)
            preview = figure;
            figure(preview);

            imshow(app.currentDisplayImg, 'InitialMagnification','fit');
            truesize(preview, [512, 512]);%実際の画像サイズを入れる予定
        end

        % Value changing function: sliceSliderSource
        function sliceSliderSourceValueChanging(app, event)
            sliceNumber = round(event.Value);
            app.currentDisplaySource = app.displayImageCells{sliceNumber};

            app.applyWindowForDisplay(app.windowLevel, app.windowWidthHalf);
            app.mountImg(app.currentDisplayImg);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiconfirm(app.UIFigure,sprintf('アプリを終了します\n再起動には時間がかかりますがよろしいですか。'),'終了', ...
            'CloseFcn',@app.appCloseConfirm);
        end

        % Button pushed function: StartAnarysisButton
        function StartAnarysisButtonPushed(app, event)
            switch app.AnalysisMode.SelectedObject.Value
                case 1
                    % 'Optical Flow' 
                    app.TabGroup.SelectedTab = app.AnalysisTab;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.TabGroup.SelectedTab
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.sourceTab = uitab(app.TabGroup);
            app.sourceTab.Title = 'DICOMSource';
            app.OPENDICOMButton = uibutton(app.sourceTab, 'push');
            app.OPENDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @OPENDICOMButtonPushed, true);
            app.OPENDICOMButton.Text = 'OPEN DICOM';
            app.SourcePreview = uiimage(app.sourceTab);
            app.SourcePreview.ImageClickedFcn = createCallbackFcn(app, @SourcePreviewClicked, true);
            app.SourcePreview.Enable = 'off';
            app.SourcePreview.ImageSource = fullfile(pathToMLAPP, 'icons', 'preview.png');
            app.sliceSliderLabel = uilabel(app.sourceTab);
            app.sliceSliderLabel.Text = 'slice';
            app.sliceSliderSource = uislider(app.sourceTab);
            app.sliceSliderSource.ValueChangingFcn = createCallbackFcn(app, @sliceSliderSourceValueChanging, true);
            app.sliceSliderSource.Enable = 'off';
            app.windowWidthSlider_2Label = uilabel(app.sourceTab);
            app.windowWidthSlider_2Label.Text = {'window'; 'Width'};
            app.WWSliderSource = uislider(app.sourceTab);
            app.WWSliderSource.Limits = [0.001 0.5];
            app.WWSliderSource.MajorTickLabels = {''};
            app.WWSliderSource.ValueChangingFcn = createCallbackFcn(app, @windowWidthChanging, true);
            app.WWSliderSource.Enable = 'off';
            app.WWSliderSource.Value = 0.5;
            app.windowLevelSlider_2Label = uilabel(app.sourceTab);
            app.windowLevelSlider_2Label.Text = {'window'; 'Level'};
            app.WLSliderSource = uislider(app.sourceTab);
            app.WLSliderSource.Limits = [0.001 0.999];
            app.WLSliderSource.MajorTickLabels = {''};
            app.WLSliderSource.ValueChangingFcn = createCallbackFcn(app, @windowLevelChanging, true);
            app.WLSliderSource.Enable = 'off';
            app.WLSliderSource.Value = 0.5;
            app.StartAnarysisButton = uibutton(app.sourceTab, 'push');
            app.StartAnarysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnarysisButtonPushed, true);
            app.StartAnarysisButton.Text = 'Start Anarysis';
            app.AnalysisMode = uibuttongroup(app.sourceTab);
            app.AnalysisMode.Title = 'Analysis Mode';
            app.OpticalFlowButton = uiradiobutton(app.AnalysisMode);
            app.OpticalFlowButton.Text = 'Optical Flow';
            app.OpticalFlowButton.Value = true;
            app.Button = uiradiobutton(app.AnalysisMode);
            app.Button.Enable = 'off';
            app.Button.Text = '未実装';
            app.Button_2 = uiradiobutton(app.AnalysisMode);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '未実装';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.analysisPreview = uiimage(app.AnalysisTab);
            app.analysisPreview.Enable = 'off';
            app.analysisPreview.ImageSource = fullfile(pathToMLAPP, 'icons', 'preview.png');
            app.sliceSlider_2Label = uilabel(app.AnalysisTab);
            app.sliceSlider_2Label.Text = 'slice';
            app.sliceSliderAnalysis = uislider(app.AnalysisTab);
            app.sliceSliderAnalysis.Enable = 'off';
            app.windowWidthSlider_2Label_2 = uilabel(app.AnalysisTab);
            app.windowWidthSlider_2Label_2.Text = {'window'; 'Width'};
            app.WWSliderAnalysis = uislider(app.AnalysisTab);
            app.WWSliderAnalysis.Limits = [0.001 0.5];
            app.WWSliderAnalysis.MajorTickLabels = {''};
            app.WWSliderAnalysis.Enable = 'off';
            app.WWSliderAnalysis.Value = 0.5;
            app.windowLevelSlider_2Label_2 = uilabel(app.AnalysisTab);
            app.windowLevelSlider_2Label_2.Text = {'window'; 'Level'};
            app.WLSliderAnalysis = uislider(app.AnalysisTab);
            app.WLSliderAnalysis.Limits = [0.001 0.999];
            app.WLSliderAnalysis.MajorTickLabels = {''};
            app.WLSliderAnalysis.Enable = 'off';
            app.WLSliderAnalysis.Value = 0.5;
            app.ProgressButtonGroup = uibuttongroup(app.AnalysisTab);
            app.ProgressButtonGroup.Title = 'Progress';
            app.ROIButton = uiradiobutton(app.ProgressButtonGroup);
            app.ROIButton.Text = 'ROI';
            app.ROIButton.Value = true;
            app.ThresholdButton = uiradiobutton(app.ProgressButtonGroup);
            app.ThresholdButton.Text = 'Threshold';
            app.MainCalucutationButton = uiradiobutton(app.ProgressButtonGroup);
            app.MainCalucutationButton.Text = 'MainCalucutation';
            app.ConfirmButton = uibutton(app.AnalysisTab, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.exportPreview = uiimage(app.ExportTab);
            app.exportPreview.Enable = 'off';
            app.exportPreview.ImageSource = fullfile(pathToMLAPP, 'icons', 'preview.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp_newBranch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end